classdef synchronizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        StartpositionPanel           matlab.ui.container.Panel
        Spinner                      matlab.ui.control.Spinner
        Panel_2                      matlab.ui.container.Panel
        LoadButton                   matlab.ui.control.Button
        Panel_6                      matlab.ui.container.Panel
        FsEditFieldLabel             matlab.ui.control.Label
        FsEditField                  matlab.ui.control.NumericEditField
        Panel_3                      matlab.ui.container.Panel
        SynchronizerLabel            matlab.ui.control.Label
        Panel_4                      matlab.ui.container.Panel
        UIAxes                       matlab.ui.control.UIAxes
        CorrelationcoefficientPanel  matlab.ui.container.Panel
        EditField                    matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        modelObj1
        modelObj2
        controlObj
        init
    end
    
    methods (Access = public)

        function update_signals(app, src, data)
            app.UIAxes.cla;
            plot(app.UIAxes, (0:length(app.modelObj1.signal)-1)/app.modelObj1.Fs,app.modelObj1.signal)
            hold(app.UIAxes,'on')
            plot(app.UIAxes,(0: length(app.modelObj2.signal)-1)/app.modelObj2.Fs, app.modelObj2.signal); 
        end
    
        function update_coeff(app, src, data)
            set(app.EditField, 'value', app.controlObj.sign_corr);
            
        end
    
        function reset_GUI(app)
            app.modelObj1 = model(1);
            app.modelObj2 = model(2);
            app.controlObj = controller(app, app.modelObj1,app.modelObj2);
            app.modelObj1.addlistener('signal_changed',@app.update_signals);    
            app.modelObj2.addlistener('signal_changed',@app.update_signals);
            app.controlObj.addlistener('corrcoeff_changed',@app.update_coeff);
            app.UIAxes.cla;
            set(app.EditField, 'value', 0);
            set(app.FsEditField, 'value', 5);
            set(app.Spinner, 'value', 1);
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            choice = 'Yes';

            if ~isempty(app.init)
                choice = questdlg('Are you sure you want to load a different signal?', ...
                    'Load a new signal', ...
                    'Yes','No','No');   
                    app.UIFigure.Visible = 'off';
                    app.UIFigure.Visible = 'on';
            end

            switch(choice)
                case 'Yes'
                    app.init=1;
                    app.reset_GUI;
                    app.controlObj.MyImport(1);
                    app.UIFigure.Visible = 'off';
                    app.UIFigure.Visible = 'on';
                    app.controlObj.MyImport(2);
                    app.UIFigure.Visible = 'off';
                    app.UIFigure.Visible = 'on';
            end
        end

        % Value changed function: FsEditField
        function FsEditFieldValueChanged(app, event)
            value = int(app.FsEditField.Value);
            app.controlObj.update_fs(value);
        end

        % Value changing function: Spinner
        function SpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.controlObj.update_signal(changingValue);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.StartpositionPanel = uipanel(app.UIFigure);
            app.StartpositionPanel.Title = 'Start position';
            app.Spinner = uispinner(app.StartpositionPanel);
            app.Spinner.ValueChangingFcn = createCallbackFcn(app, @SpinnerValueChanging, true);
            app.Spinner.Value = 1;
            app.Panel_2 = uipanel(app.UIFigure);
            app.LoadButton = uibutton(app.Panel_2, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.Panel_6 = uipanel(app.Panel_2);
            app.FsEditFieldLabel = uilabel(app.Panel_6);
            app.FsEditFieldLabel.Text = 'Fs';
            app.FsEditField = uieditfield(app.Panel_6, 'numeric');
            app.FsEditField.ValueChangedFcn = createCallbackFcn(app, @FsEditFieldValueChanged, true);
            app.FsEditField.Value = 5;
            app.Panel_3 = uipanel(app.UIFigure);
            app.SynchronizerLabel = uilabel(app.Panel_3);
            app.SynchronizerLabel.Text = 'Synchronizer';
            app.Panel_4 = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.Panel_4);
            xlabel(app.UIAxes, 'Sample')
            ylabel(app.UIAxes, 'Amplitude')
            app.CorrelationcoefficientPanel = uipanel(app.UIFigure);
            app.CorrelationcoefficientPanel.Title = 'Correlation coefficient';
            app.EditField = uieditfield(app.CorrelationcoefficientPanel, 'numeric');
            app.EditField.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = synchronizer

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end